classdef ImagePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodageSoruceImageUIFigure  matlab.ui.Figure
        BackButton                 matlab.ui.control.Button
        InsertyourPictureLabel     matlab.ui.control.Label
        InsertaPictureButton       matlab.ui.control.Button
        ENCODEButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertaPictureButton
        function InsertaPictureButtonPushed(app, event)
            % Open a file dialog to choose an image file
            [filename, filepath] = uigetfile({'*.jpg;*.png;', 'Image Files (*.jpg, *.png)'});

            % Check if a file was selected
            if ischar(filename)
                % Load the selected image
                imageFilePath = fullfile(filepath, filename);
                imageData = imread(imageFilePath);

                % Display the selected image in the image component
                app.Image.ImageSource = imageData;
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Home
            close(app.CodageSoruceImageUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodageSoruceImageUIFigure = uifigure('Visible', 'off');
            app.CodageSoruceImageUIFigure.Name = 'Codage Soruce Image';
            app.ENCODEButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.ENCODEButton.Text = 'ENCODE';
            app.InsertaPictureButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.InsertaPictureButton.ButtonPushedFcn = createCallbackFcn(app, @InsertaPictureButtonPushed, true);
            app.InsertaPictureButton.Text = 'Insert a Picture';
            app.InsertyourPictureLabel = uilabel(app.CodageSoruceImageUIFigure);
            app.InsertyourPictureLabel.Text = 'Insert your Picture';
            app.BackButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'icon1.png';
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.CodageSoruceImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagePanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodageSoruceImageUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodageSoruceImageUIFigure)
        end
    end
end